classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SignalTab                       matlab.ui.container.Tab
        TempsdedbutdchellonEditFieldLabel  matlab.ui.control.Label
        TempsdedbutdchellonEditField    matlab.ui.control.NumericEditField
        TempsdefindchellonEditFieldLabel  matlab.ui.control.Label
        TempsdefindchellonEditField     matlab.ui.control.NumericEditField
        TempsdefindepriodeEditFieldLabel  matlab.ui.control.Label
        TempsdefindepriodeEditField     matlab.ui.control.NumericEditField
        TempsdedbutdepriodeEditFieldLabel  matlab.ui.control.Label
        TempsdedbutdepriodeEditField    matlab.ui.control.NumericEditField
        PotentieldereposcelluleEditFieldLabel  matlab.ui.control.Label
        PotentieldereposcelluleEditField  matlab.ui.control.NumericEditField
        PotentieldestimulationEditFieldLabel  matlab.ui.control.Label
        PotentieldestimulationEditField  matlab.ui.control.NumericEditField
        FrquencedestimulationEditFieldLabel  matlab.ui.control.Label
        FrquencedestimulationEditField  matlab.ui.control.NumericEditField
        Tab2                            matlab.ui.container.Tab
        RUNButton                       matlab.ui.control.Button
        Tab3                            matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function
        function CheckBoxValueChanged(app, event)
            
        end

        % Size changed function: Tab2
        function RUNButtonValueChanged(app, event)
           
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            assignin("base",'temps0',app.TempsdedbutdepriodeEditField.Value)
            assignin("base",'temps1',app.TempsdedbutdchellonEditField.Value)
            assignin("base",'temps2',app.TempsdefindchellonEditField.Value)
            assignin("base",'temps3',app.TempsdefindepriodeEditField.Value)
            assignin("base",'Vrepos',app.PotentieldereposcelluleEditField.Value)
            assignin("base", "Vstim", app.PotentieldestimulationEditField.Value)
            assignin("base", "fe", app.FrquencedestimulationEditField.Value)
            
            run('C:\Users\holvo\OneDrive\Documents\Projet INM\PourMarion-20191129T143556Z-001\PourMarion\SimulationICa\SimulationCourant')
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SignalTab = uitab(app.TabGroup);
            app.SignalTab.Title = 'Signal';
            app.TempsdedbutdchellonEditFieldLabel = uilabel(app.SignalTab);
            app.TempsdedbutdchellonEditFieldLabel.Text = 'Temps  de début d''échellon';
            app.TempsdedbutdchellonEditField = uieditfield(app.SignalTab, 'numeric');
            app.TempsdefindchellonEditFieldLabel = uilabel(app.SignalTab);
            app.TempsdefindchellonEditFieldLabel.Text = 'Temps de fin d''échellon';
            app.TempsdefindchellonEditField = uieditfield(app.SignalTab, 'numeric');
            app.TempsdefindepriodeEditFieldLabel = uilabel(app.SignalTab);
            app.TempsdefindepriodeEditFieldLabel.Text = 'Temps de fin de période';
            app.TempsdefindepriodeEditField = uieditfield(app.SignalTab, 'numeric');
            app.TempsdedbutdepriodeEditFieldLabel = uilabel(app.SignalTab);
            app.TempsdedbutdepriodeEditFieldLabel.Text = 'Temps de début de période';
            app.TempsdedbutdepriodeEditField = uieditfield(app.SignalTab, 'numeric');
            app.PotentieldereposcelluleEditFieldLabel = uilabel(app.SignalTab);
            app.PotentieldereposcelluleEditFieldLabel.Text = 'Potentiel de repos cellule';
            app.PotentieldereposcelluleEditField = uieditfield(app.SignalTab, 'numeric');
            app.PotentieldestimulationEditFieldLabel = uilabel(app.SignalTab);
            app.PotentieldestimulationEditFieldLabel.Text = 'Potentiel de stimulation';
            app.PotentieldestimulationEditField = uieditfield(app.SignalTab, 'numeric');
            app.FrquencedestimulationEditFieldLabel = uilabel(app.SignalTab);
            app.FrquencedestimulationEditFieldLabel.Text = 'Fréquence de stimulation';
            app.FrquencedestimulationEditField = uieditfield(app.SignalTab, 'numeric');
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.SizeChangedFcn = createCallbackFcn(app, @RUNButtonValueChanged, true);
            app.Tab2.Title = 'Tab2';
            app.RUNButton = uibutton(app.Tab2, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.UIAxes = uiaxes(app.Tab3);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end