classdef projet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        FileTab                        matlab.ui.container.Tab
        CommandeTab                    matlab.ui.container.Tab
        TempsdedbutdchellonsEditFieldLabel  matlab.ui.control.Label
        TempsdedbutdchellonsEditField  matlab.ui.control.NumericEditField
        TempsdefindchellonsEditFieldLabel  matlab.ui.control.Label
        TempsdefindchellonsEditField   matlab.ui.control.NumericEditField
        TempsdefindepriodesEditFieldLabel  matlab.ui.control.Label
        TempsdefindepriodesEditField   matlab.ui.control.NumericEditField
        TempsdedbutdepriodesEditFieldLabel  matlab.ui.control.Label
        TempsdedbutdepriodesEditField  matlab.ui.control.NumericEditField
        PotentieldereposcellulemVEditFieldLabel  matlab.ui.control.Label
        PotentieldereposcellulemVEditField  matlab.ui.control.NumericEditField
        PotentieldestimulationmVEditFieldLabel  matlab.ui.control.Label
        PotentieldestimulationmVEditField  matlab.ui.control.NumericEditField
        FrquencedestimulationHzEditFieldLabel  matlab.ui.control.Label
        FrquencedestimulationHzEditField  matlab.ui.control.NumericEditField
        SignalDropDownLabel            matlab.ui.control.Label
        SignalDropDown                 matlab.ui.control.DropDown
        CanauxCalciquesTab             matlab.ui.container.Tab
        Tab2                           matlab.ui.container.Tab
        RUNButton                      matlab.ui.control.Button
        Tab3                           matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function
        function CheckBoxValueChanged(app, event)
            
        end

        % Size changed function: Tab2
        function RUNButtonValueChanged(app, event)
           
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            assignin("base",'temps0',app.TempsdedbutdepriodesEditField.Value)
            assignin("base",'temps1',app.TempsdedbutdchellonsEditField.Value)
            assignin("base",'temps2',app.TempsdefindchellonsEditField.Value)
            assignin("base",'temps3',app.TempsdefindepriodesEditField.Value)
            assignin("base",'Vrepos',app.PotentieldereposcellulemVEditField.Value)
            assignin("base", "Vstim", app.PotentieldestimulationmVEditField.Value)
            assignin("base", "fe", app.FrquencedestimulationHzEditField.Value)
            
            run('C:\Users\holvo\OneDrive\Documents\Projet INM\PourMarion-20191129T143556Z-001\PourMarion\SimulationICa\SimulationCourant')
            
        end

        % Callback function
        function StepSignalCheckBoxValueChanged(app, event)
            value = app.StepSignalCheckBox.Value;
            if value
                
                
            end
        end

        % Value changed function: SignalDropDown
        function EntrySignal(app, event)
            value = app.SignalDropDown.Value;
            if Value=="Echelon"
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FileTab = uitab(app.TabGroup);
            app.FileTab.Title = 'File';
            app.CommandeTab = uitab(app.TabGroup);
            app.CommandeTab.Title = 'Commande';
            app.TempsdedbutdchellonsEditFieldLabel = uilabel(app.CommandeTab);
            app.TempsdedbutdchellonsEditFieldLabel.Text = 'Temps  de début d''échellon (s)';
            app.TempsdedbutdchellonsEditField = uieditfield(app.CommandeTab, 'numeric');
            app.TempsdefindchellonsEditFieldLabel = uilabel(app.CommandeTab);
            app.TempsdefindchellonsEditFieldLabel.Text = 'Temps de fin d''échellon (s)';
            app.TempsdefindchellonsEditField = uieditfield(app.CommandeTab, 'numeric');
            app.TempsdefindepriodesEditFieldLabel = uilabel(app.CommandeTab);
            app.TempsdefindepriodesEditFieldLabel.Text = 'Temps de fin de période (s)';
            app.TempsdefindepriodesEditField = uieditfield(app.CommandeTab, 'numeric');
            app.TempsdedbutdepriodesEditFieldLabel = uilabel(app.CommandeTab);
            app.TempsdedbutdepriodesEditFieldLabel.Text = 'Temps de début de période (s)';
            app.TempsdedbutdepriodesEditField = uieditfield(app.CommandeTab, 'numeric');
            app.PotentieldereposcellulemVEditFieldLabel = uilabel(app.CommandeTab);
            app.PotentieldereposcellulemVEditFieldLabel.Text = 'Potentiel de repos cellule (mV)';
            app.PotentieldereposcellulemVEditField = uieditfield(app.CommandeTab, 'numeric');
            app.PotentieldestimulationmVEditFieldLabel = uilabel(app.CommandeTab);
            app.PotentieldestimulationmVEditFieldLabel.Text = 'Potentiel de stimulation (mV)';
            app.PotentieldestimulationmVEditField = uieditfield(app.CommandeTab, 'numeric');
            app.FrquencedestimulationHzEditFieldLabel = uilabel(app.CommandeTab);
            app.FrquencedestimulationHzEditFieldLabel.Text = 'Fréquence de stimulation (Hz)';
            app.FrquencedestimulationHzEditField = uieditfield(app.CommandeTab, 'numeric');
            app.SignalDropDownLabel = uilabel(app.CommandeTab);
            app.SignalDropDownLabel.Text = 'Signal';
            app.SignalDropDown = uidropdown(app.CommandeTab);
            app.SignalDropDown.Items = {'Echelon', 'Double Echelon', 'Rampe'};
            app.SignalDropDown.ValueChangedFcn = createCallbackFcn(app, @EntrySignal, true);
            app.SignalDropDown.Value = 'Rampe';
            app.CanauxCalciquesTab = uitab(app.TabGroup);
            app.CanauxCalciquesTab.Title = 'Canaux Calciques';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.SizeChangedFcn = createCallbackFcn(app, @RUNButtonValueChanged, true);
            app.Tab2.Title = 'Tab2';
            app.RUNButton = uibutton(app.Tab2, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.UIAxes = uiaxes(app.Tab3);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = projet

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end