classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GenderButtonGroup         matlab.ui.container.ButtonGroup
        FemaleButton              matlab.ui.control.RadioButton
        MaleButton                matlab.ui.control.RadioButton
        BloodPressureSwitchLabel  matlab.ui.control.Label
        BloodPressureSwitch       matlab.ui.control.Switch
        PlottttButton             matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlottttButton
        function PlottttButtonPushed(app, event)
            load patients.mat
            
            bp = app.BloodPressureSwitch.Value;
            if bp == "Systolic"
                if app.FemaleButton.Value == true
                    x = Weight(Gender == "Female");
                    y = Systolic(Gender == "Female");
                elseif app.MaleButton.Value == true
                    x = Weight(Gender == "Male");
                    y = Systolic(Gender == "Male");                    
                end
            else
                if app.FemaleButton.Value == true
                    x = Weight(Gender == "Female");
                    y = Diastolic(Gender == "Female");
                elseif app.MaleButton.Value == true
                    x = Weight(Gender == "Male");
                    y = Diastolic(Gender == "Male");                    
                end
            end
            
            scatter(app.UIAxes,x,y)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GenderButtonGroup = uibuttongroup(app.UIFigure);
            app.GenderButtonGroup.Title = 'Gender';
            app.FemaleButton = uiradiobutton(app.GenderButtonGroup);
            app.FemaleButton.Text = 'Female';
            app.FemaleButton.Value = true;
            app.MaleButton = uiradiobutton(app.GenderButtonGroup);
            app.MaleButton.Text = 'Male';
            app.BloodPressureSwitchLabel = uilabel(app.UIFigure);
            app.BloodPressureSwitchLabel.Text = 'Blood Pressure';
            app.BloodPressureSwitch = uiswitch(app.UIFigure, 'slider');
            app.BloodPressureSwitch.Items = {'Systolic', 'Diastolic'};
            app.BloodPressureSwitch.Value = 'Systolic';
            app.PlottttButton = uibutton(app.UIFigure, 'push');
            app.PlottttButton.ButtonPushedFcn = createCallbackFcn(app, @PlottttButtonPushed, true);
            app.PlottttButton.Text = 'Plotttt';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Weight vs. Blood Pressure')
            xlabel(app.UIAxes, 'Weight')
            ylabel(app.UIAxes, 'Blood Pressure')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end